classdef UNOCardGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StartGameButton  matlab.ui.control.Button
        PlaceCard        matlab.ui.control.Image
        TakeCard         matlab.ui.control.Image
        Slot8            matlab.ui.control.Image
        Slot7            matlab.ui.control.Image
        Slot6            matlab.ui.control.Image
        Slot5            matlab.ui.control.Image
        Slot4            matlab.ui.control.Image
        Slot3            matlab.ui.control.Image
        Slot2            matlab.ui.control.Image
        Slot1            matlab.ui.control.Image
        Player2          matlab.ui.control.Image
        Player1          matlab.ui.control.Image
        Board            matlab.ui.control.Image
        Background       matlab.ui.control.Image
    end

    
    properties (Access = private)
        UNOCardTable = UNOCardData;
        previousCardID = 0; % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.UNOCardTable = Deck_Creator(app.UNOCardTable);
            [hands, app.UNOCardTable, ~] = Hands_Creator(2, app.UNOCardTable);
            disp(hands.Player1(1));
            %%Testing only -- Making the image pop up when the game starts
            app.Slot1.ImageSource = IDtoName(hands.Player1(1), app.UNOCardTable);
            app.Slot1.Visible = "on";
            app.Slot2.ImageSource = IDtoName(hands.Player1(2), app.UNOCardTable);
            app.Slot2.Visible = "on";
            app.Slot3.ImageSource = IDtoName(hands.Player1(3), app.UNOCardTable);
            app.Slot3.Visible = "on";
            app.Slot4.ImageSource = IDtoName(hands.Player1(4), app.UNOCardTable);
            app.Slot4.Visible = "on";
            app.Slot5.ImageSource = IDtoName(hands.Player1(5), app.UNOCardTable);
            app.Slot5.Visible = "on";
            app.Slot6.ImageSource = IDtoName(hands.Player1(6), app.UNOCardTable);
            app.Slot6.Visible = "on";
            app.Slot7.ImageSource = IDtoName(hands.Player1(7), app.UNOCardTable);
            app.Slot7.Visible = "on";


            app.previousCardID = app.UNOCardTable.ID(find(app.UNOCardTable.Played == 0, 1));
            app.PlaceCard.ImageSource = IDtoName(app.previousCardID, app.UNOCardTable);
            
            
        end

        % Image clicked function: Slot1
        function Slot1ImageClicked(app, event)
            app.PlaceCard.ImageSource = app.Slot1.ImageSource;
            app.previousCardID = 3;
            app.Slot1.ImageSource = app.Slot2.ImageSource;
            app.Slot1.Visible = app.Slot2.Visible;
            app.Slot2.ImageSource = app.Slot3.ImageSource;
            app.Slot2.Visible = app.Slot3.Visible;
            app.Slot3.ImageSource = app.Slot4.ImageSource;
            app.Slot3.Visible = app.Slot4.Visible;
            app.Slot4.ImageSource = app.Slot5.ImageSource;
            app.Slot4.Visible = app.Slot5.Visible;
            app.Slot5.ImageSource = app.Slot6.ImageSource;
            app.Slot5.Visible = app.Slot6.Visible;
            app.Slot6.ImageSource = app.Slot7.ImageSource;
            app.Slot6.Visible = app.Slot7.Visible;
            app.UNOCardTable = Deck_Creator(app.UNOCardTable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Background.png');
            app.Board = uiimage(app.UIFigure);
            app.Board.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Table.png');
            app.Player1 = uiimage(app.UIFigure);
            app.Player1.ImageSource = fullfile(pathToMLAPP, 'Assets', 'People.png');
            app.Player2 = uiimage(app.UIFigure);
            app.Player2.ImageSource = fullfile(pathToMLAPP, 'Assets', 'People.png');
            app.Slot1 = uiimage(app.UIFigure);
            app.Slot1.ImageClickedFcn = createCallbackFcn(app, @Slot1ImageClicked, true);
            app.Slot1.Visible = 'off';
            app.Slot1.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot2 = uiimage(app.UIFigure);
            app.Slot2.Visible = 'off';
            app.Slot2.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot3 = uiimage(app.UIFigure);
            app.Slot3.Visible = 'off';
            app.Slot3.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot4 = uiimage(app.UIFigure);
            app.Slot4.Visible = 'off';
            app.Slot4.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot5 = uiimage(app.UIFigure);
            app.Slot5.Visible = 'off';
            app.Slot5.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot6 = uiimage(app.UIFigure);
            app.Slot6.Visible = 'off';
            app.Slot6.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot7 = uiimage(app.UIFigure);
            app.Slot7.Visible = 'off';
            app.Slot7.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.Slot8 = uiimage(app.UIFigure);
            app.Slot8.Visible = 'off';
            app.Slot8.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'Red1.png');
            app.TakeCard = uiimage(app.UIFigure);
            app.TakeCard.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'UNOCardBack.png');
            app.PlaceCard = uiimage(app.UIFigure);
            app.PlaceCard.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Cards', 'NoCard.png');
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UNOCardGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end